classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GoogleNetPanel_2        matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        RedimensionarimagenTab  matlab.ui.container.Tab
        SubirImagenTab          matlab.ui.container.Tab
        AjustesdeparmetrosTab   matlab.ui.container.Tab
        GoogleNetPanel          matlab.ui.container.Panel
        CLASIFICATIONLabelG     matlab.ui.control.Label
        TRAININGLabelG          matlab.ui.control.Label
        ImageBackG              matlab.ui.control.Image
        ImageClasificationG     matlab.ui.control.Image
        ImageTrainingG          matlab.ui.control.Image
        AlexNetPanel            matlab.ui.container.Panel
        CLASIFICATIONLabelA     matlab.ui.control.Label
        TRAININGLabelA          matlab.ui.control.Label
        ImageBackA              matlab.ui.control.Image
        ImageClasificationA     matlab.ui.control.Image
        ImageTrainingA          matlab.ui.control.Image
        SecondPanel             matlab.ui.container.Panel
        GOOGLENETLabel          matlab.ui.control.Label
        ALEXNETLabel            matlab.ui.control.Label
        ImageBack               matlab.ui.control.Image
        ImageGoogleNet          matlab.ui.control.Image
        ImageAlexNet            matlab.ui.control.Image
        PrincipalPanel          matlab.ui.container.Panel
        STARTButton             matlab.ui.control.Button
        ImagePrincipal          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.PrincipalPanel.Visible = 'off';
            app.SecondPanel.Visible = 'on';
        end

        % Image clicked function: ImageBack
        function ImageBackClicked(app, event)
            app.SecondPanel.Visible = 'off';
            app.PrincipalPanel.Visible = 'on';
        end

        % Callback function
        function ImageBack_2Clicked(app, event)
            app.AlexNetPanel.Visible = 'off';
            app.SecondPanel.Visible = 'on';
        end

        % Image clicked function: ImageAlexNet
        function ImageAlexNetClicked(app, event)
            app.SecondPanel.Visible = 'off';
            app.AlexNetPanel.Visible = 'on';
        end

        % Image clicked function: ImageGoogleNet
        function ImageGoogleNetClicked(app, event)
            app.SecondPanel.Visible = 'off';
            app.GoogleNetPanel.Visible = 'on';
        end

        % Image clicked function: ImageBackA
        function ImageBackAClicked2(app, event)
            app.AlexNetPanel.Visible = 'off';
            app.SecondPanel.Visible = 'on';
        end

        % Image clicked function: ImageBackG
        function ImageBackGClicked(app, event)
            app.GoogleNetPanel.Visible = 'off';
            app.SecondPanel.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PrincipalPanel = uipanel(app.UIFigure);
            app.PrincipalPanel.Title = 'RECONOCIMIENTO DE VEHÍCULOS';
            app.ImagePrincipal = uiimage(app.PrincipalPanel);
            app.ImagePrincipal.ImageSource = 'conexion.png';
            app.STARTButton = uibutton(app.PrincipalPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = {'START'; ''};
            app.SecondPanel = uipanel(app.UIFigure);
            app.SecondPanel.Title = 'SELECCIÓN DE MODELO DE RED';
            app.SecondPanel.Visible = 'off';
            app.ImageAlexNet = uiimage(app.SecondPanel);
            app.ImageAlexNet.ImageClickedFcn = createCallbackFcn(app, @ImageAlexNetClicked, true);
            app.ImageAlexNet.ImageSource = 'A.png';
            app.ImageGoogleNet = uiimage(app.SecondPanel);
            app.ImageGoogleNet.ImageClickedFcn = createCallbackFcn(app, @ImageGoogleNetClicked, true);
            app.ImageGoogleNet.ImageSource = 'G.png';
            app.ImageBack = uiimage(app.SecondPanel);
            app.ImageBack.ImageClickedFcn = createCallbackFcn(app, @ImageBackClicked, true);
            app.ImageBack.ImageSource = 'atras.png';
            app.ALEXNETLabel = uilabel(app.SecondPanel);
            app.ALEXNETLabel.Text = 'ALEXNET';
            app.GOOGLENETLabel = uilabel(app.SecondPanel);
            app.GOOGLENETLabel.Text = 'GOOGLENET';
            app.AlexNetPanel = uipanel(app.UIFigure);
            app.AlexNetPanel.Title = 'ALEXNET';
            app.AlexNetPanel.Visible = 'off';
            app.ImageTrainingA = uiimage(app.AlexNetPanel);
            app.ImageTrainingA.ImageSource = 'training.png';
            app.ImageClasificationA = uiimage(app.AlexNetPanel);
            app.ImageClasificationA.ImageSource = 'clasification.png';
            app.ImageBackA = uiimage(app.AlexNetPanel);
            app.ImageBackA.ImageClickedFcn = createCallbackFcn(app, @ImageBackAClicked2, true);
            app.ImageBackA.ImageSource = 'atras.png';
            app.TRAININGLabelA = uilabel(app.AlexNetPanel);
            app.TRAININGLabelA.Text = 'TRAINING';
            app.CLASIFICATIONLabelA = uilabel(app.AlexNetPanel);
            app.CLASIFICATIONLabelA.Text = 'CLASIFICATION';
            app.GoogleNetPanel = uipanel(app.UIFigure);
            app.GoogleNetPanel.Title = 'GOOGLENET';
            app.GoogleNetPanel.Visible = 'off';
            app.ImageTrainingG = uiimage(app.GoogleNetPanel);
            app.ImageTrainingG.ImageSource = 'training.png';
            app.ImageClasificationG = uiimage(app.GoogleNetPanel);
            app.ImageClasificationG.ImageSource = 'clasification.png';
            app.ImageBackG = uiimage(app.GoogleNetPanel);
            app.ImageBackG.ImageClickedFcn = createCallbackFcn(app, @ImageBackGClicked, true);
            app.ImageBackG.ImageSource = 'atras.png';
            app.TRAININGLabelG = uilabel(app.GoogleNetPanel);
            app.TRAININGLabelG.Text = 'TRAINING';
            app.CLASIFICATIONLabelG = uilabel(app.GoogleNetPanel);
            app.CLASIFICATIONLabelG.Text = 'CLASIFICATION';
            app.GoogleNetPanel_2 = uipanel(app.UIFigure);
            app.GoogleNetPanel_2.Title = 'TRAINING ALEX NET';
            app.TabGroup = uitabgroup(app.GoogleNetPanel_2);
            app.RedimensionarimagenTab = uitab(app.TabGroup);
            app.RedimensionarimagenTab.Title = 'Redimensionar imagen';
            app.SubirImagenTab = uitab(app.TabGroup);
            app.SubirImagenTab.Title = 'Subir Imagen';
            app.AjustesdeparmetrosTab = uitab(app.TabGroup);
            app.AjustesdeparmetrosTab.Title = 'Ajustes de parámetros';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end